classdef SkylarkDroneAgent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        Label     matlab.ui.control.Label
    end

    
properties (Access = private)
    PilotRosterTable      matlab.ui.control.UITable  % Pilot roster data table
    QueryField            matlab.ui.control.EditField  % Search input
    SearchButton          matlab.ui.control.Button  % Search button
    ConnectButton         matlab.ui.control.Button  % Google Sheets connect
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
    % Skylark Drones - Drone Operations Coordinator AI Agent
    % Startup function for App Designer - Initialize UI elements
    
    %% Initialize App Properties
    app.CurrentDateTime = datetime('now');
    app.GoogleSheetsLinked = false;
    app.LastSyncTime = NaT;
    
    %% Header and Branding
    app.TitleLabel.Text = "Skylark Drones AI Coordinator";
    app.TitleLabel.FontSize = 24;
    app.TitleLabel.FontWeight = 'bold';
    app.TitleLabel.FontColor = [0.2 0.4 0.8]; % Blue theme
    
    app.SubtitleLabel.Text = "Pilot & Drone Operations Management";
    app.SubtitleLabel.FontSize = 14;
    app.SubtitleLabel.FontColor = [0.5 0.5 0.5];
    
    %% Status Panel Icons and Labels
    setupStatusPanel(app);
    
    %% Navigation Panel Icons
    setupNavigationPanel(app);
    
    %% Data Panels Icons
    setupDataPanels(app);
    
    %% Initialize Tabs
    app.TabGroup.SelectedTab = app.RosterTab;
    
    %% Welcome Message
    app.StatusMessageLabel.Text = "AI Coordinator Ready. Query pilots, drones, or missions.";
    app.StatusMessageLabel.FontColor = [0.1 0.6 0.1];
    
    % Log startup
    fprintf('Skylark Drones AI Agent initialized at %s\n', datestr(now));
end

function setupStatusPanel(app)
    % Status Panel - Connection & Sync Icons
    app.StatusPanel.BackgroundColor = [0.95 0.95 0.95];
    
    % Google Sheets Status Icon (Green/Red circle)
    app.SheetsStatusIcon.BackgroundColor = [0.8 0.2 0.2]; % Red initially
    app.SheetsStatusIcon.Position = [10 80 20 20];
    app.SheetsStatusLabel.Text = "Sheets: Disconnected";
    app.SheetsStatusLabel.Position = [35 82 120 18];
    
    % Last Sync Icon (Clock)
    app.SyncIcon.BackgroundColor = [0.9 0.9 0.3];
    app.SyncIcon.Position = [10 50 20 20];
    app.SyncStatusLabel.Text = "Last Sync: Never";
    app.SyncStatusLabel.Position = [35 52 120 18];
    
    % AI Status Icon (Robot head)
    app.AIStatusIcon.BackgroundColor = [0.2 0.8 0.2]; % Green
    app.AIStatusIcon.Position = [10 20 20 20];
    app.AIStatusLabel.Text = "AI: Online";
    app.AIStatusLabel.Position = [35 22 80 18];
end

function setupNavigationPanel(app)
    % Navigation Panel - Tab Icons
    app.NavPanel.BackgroundColor = [0.98 0.98 0.98];
    
    % Roster Tab Icon (People silhouette)
    app.RosterIcon.BackgroundColor = [0.3 0.6 0.9];
    app.RosterIcon.Position = [15 120 24 24];
    app.RosterLabel.Text = "👨‍✈️ Pilots";
    app.RosterLabel.Position = [45 122 80 20];
    app.RosterLabel.FontSize = 12;
    app.RosterLabel.FontWeight = 'bold';
    
    % Drone Tab Icon (Drone silhouette)
    app.DroneIcon.BackgroundColor = [0.1 0.7 0.3];
    app.DroneIcon.Position = [15 90 24 24];
    app.DroneLabel.Text = "🚁 Drones";
    app.DroneLabel.Position = [45 92 80 20];
    app.DroneLabel.FontSize = 12;
    app.DroneLabel.FontWeight = 'bold';
    
    % Mission Tab Icon (Target/Map)
    app.MissionIcon.BackgroundColor = [0.9 0.4 0.2];
    app.MissionIcon.Position = [15 60 24 24];
    app.MissionLabel.Text = "🎯 Missions";
    app.MissionLabel.Position = [45 62 80 20];
    app.MissionLabel.FontSize = 12;
    app.MissionLabel.FontWeight = 'bold';
    
    % Conflicts Tab Icon (Warning triangle)
    app.ConflictIcon.BackgroundColor = [0.85 0.3 0.3];
    app.ConflictIcon.Position = [15 30 24 24];
    app.ConflictLabel.Text = "⚠️ Conflicts";
    app.ConflictLabel.Position = [45 32 80 20];
    app.ConflictLabel.FontSize = 12;
    app.ConflictLabel.FontWeight = 'bold';
end

function setupDataPanels(app)
    % Main Data Panels - Section Icons
    
    % Pilot Roster Panel
    app.PilotPanel.BackgroundColor = [1 1 0.95];
    app.PilotIcon.BackgroundColor = [0.2 0.6 1];
    app.PilotIcon.Position = [10 10 32 32];
    app.PilotHeaderLabel.Text = "Pilot Roster";
    app.PilotHeaderLabel.FontSize = 16;
    app.PilotHeaderLabel.FontWeight = 'bold';
    app.PilotHeaderLabel.Position = [50 15 120 25];
    
    % Drone Fleet Panel
    app.DronePanel.BackgroundColor = [0.95 1 0.95];
    app.DroneFleetIcon.BackgroundColor = [0.1 0.8 0.2];
    app.DroneFleetIcon.Position = [10 10 32 32];
    app.DroneHeaderLabel.Text = "Drone Fleet";
    app.DroneHeaderLabel.FontSize = 16;
    app.DroneHeaderLabel.FontWeight = 'bold';
    app.DroneHeaderLabel.Position = [50 15 100 25];
    
    % Mission Panel
    app.MissionPanel.BackgroundColor = [1 0.95 0.95];
    app.MissionFleetIcon.BackgroundColor = [0.9 0.3 0.3];
    app.MissionFleetIcon.Position = [10 10 32 32];
    app.MissionHeaderLabel.Text = "Active Missions";
    app.MissionHeaderLabel.FontSize = 16;
    app.MissionHeaderLabel.FontWeight = 'bold';
    app.MissionHeaderLabel.Position = [50 15 130 25];
app.PilotRosterTable.Data = {'John','YOLO','Available'};
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SkylarkDroneAgent

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end