classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

properties (Access = public)
    TitleLabel          matlab.ui.control.Label
    TabGroup            matlab.ui.container.TabGroup
    RosterTab           matlab.ui.container.Tab
    DroneTab            matlab.ui.container.Tab
    MissionTab          matlab.ui.container.Tab
    ConflictTab         matlab.ui.container.Tab
    PilotTable          matlab.ui.control.UITable
    DroneTable          matlab.ui.control.UITable
    MissionTable        matlab.ui.control.UITable
    ConflictList        matlab.ui.control.UITable
    QueryField          matlab.ui.control.EditField
    SearchButton        matlab.ui.control.Button
    ConnectSheetsButton matlab.ui.control.Button
    UpdateStatusButton  matlab.ui.control.Button
    StatusLabel         matlab.ui.control.Label
end
        function createComponents(app)
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            app.TitleLabel = uilabel(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RosterTab.Title = 'Pilots';
            app.DroneTab.Title = 'Drones';
            app.MissionTab.Title = 'Missions';
            app.ConflictTab.Title = 'Conflicts';
            app.PilotTable = uitable(app.RosterTab);
            app.DroneTable = uitable(app.DroneTab);
            app.MissionTable = uitable(app.MissionTab);
            app.ConflictList = uitable(app.ConflictTab);
            app.QueryField = uieditfield(app.UIFigure,'text');
    methods (Access = private)
        function results = func(app)
        end
    end


    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end